classdef ROBOTICS_ARM_PROJECT_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UserManualButton     matlab.ui.control.Button
        SimulateButton       matlab.ui.control.Button
        Link2EditField       matlab.ui.control.NumericEditField
        Link2EditFieldLabel  matlab.ui.control.Label
        Link1EditField       matlab.ui.control.NumericEditField
        Link1EditFieldLabel  matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
%Robotics arm 2D  2 links project App 
%Input the links for the robotic arm
% Get link lengths from GUI fields
Link1 = app.Link1EditField.Value;
Link2 = app.Link2EditField.Value;


%Defining the angles for forward kinematics and spacing them
Angle1 = linspace(0,90,20);
Angle2 = linspace(0,90,20);


%Starting the loop for the robot arm

for i =1:length(Angle1)
        for  j = 1:length(Angle2)
            Ang1=Angle1(i);
            Ang2=Angle2(j);
        

%Forward kinematics--------------------------------------------------------
%iniial
x0=0;
y0=0;
%For the first link
x1 = Link1 * cosd(Ang1);      %We use cosd not cos so that we will be able to use in degrees mode
y1 = Link1 * sind(Ang1);      %Also here we used sind same as purpose for cosd
%For the second link
x2 = x1 + Link2*cosd(Ang1+Ang2);
y2 = y1 + Link2*sind(Ang1+Ang2);

%Plotting graph-----------------------------------------------------------
% Clear previous plot
cla(app.UIAxes);
plot(app.UIAxes,[x0,x1],[y0,y1],'LineWidth',5,'Color','r');
hold (app.UIAxes,'on')
plot(app.UIAxes,[x1,x2],[y1,y2],'LineWidth',5,'Color','r');

axis(app.UIAxes,[-10 10 -10 10]);  %setting the range of x and y axes manually for the plot

% Draw current frame
drawnow;

%Animation part---------------------------------------------------------
pause(0.05) %to control ther animation speed by making a short delay between the frames
grid (app.UIAxes,'on')

        end
end

        end

        % Button pushed function: UserManualButton
        function UserManualButtonPushed(app, event)

            web('User_Manual.pdf', '-browser');  %Opens the user Manual

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Rbotoics Arm simulation')
            xlabel(app.UIAxes, 'X-axis')
            ylabel(app.UIAxes, 'Y-axis')
            zlabel(app.UIAxes, 'Z')
            app.Link1EditFieldLabel = uilabel(app.UIFigure);
            app.Link1EditFieldLabel.Text = 'Link1';
            app.Link1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Link2EditFieldLabel = uilabel(app.UIFigure);
            app.Link2EditFieldLabel.Text = 'Link2';
            app.Link2EditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.UserManualButton = uibutton(app.UIFigure, 'push');
            app.UserManualButton.ButtonPushedFcn = createCallbackFcn(app, @UserManualButtonPushed, true);
            app.UserManualButton.Text = 'UserManual';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROBOTICS_ARM_PROJECT_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end